classdef GUI_RLC2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ZakresmaxfkHzSlider       matlab.ui.control.Slider
        ZakresmaxfkHzSliderLabel  matlab.ui.control.Label
        ZakresminfkHzSlider       matlab.ui.control.Slider
        ZakresminfkHzSliderLabel  matlab.ui.control.Label
        C0nFEditField             matlab.ui.control.NumericEditField
        C0nFEditFieldLabel        matlab.ui.control.Label
        MaterialSwitch            matlab.ui.control.Switch
        MaterialSwitchLabel       matlab.ui.control.Label
        LmHEditField              matlab.ui.control.NumericEditField
        LmHEditFieldLabel         matlab.ui.control.Label
        CnFEditField              matlab.ui.control.NumericEditField
        CFLabel                   matlab.ui.control.Label
        ROhmEditField             matlab.ui.control.NumericEditField
        ROhmEditFieldLabel        matlab.ui.control.Label
        PlotButton                matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        R=144.2;
        L=218.7*1e-3;
        C=0.0437*1e-9;
        C0=3.79*1e-9;
        minFreq=45*1e3;
        maxFreq=60*1e3;
        Material
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
           n=num2str(app.Material)
            hold(app.UIAxes,"off")
            hold(app.UIAxes2,"off")
            hold(app.UIAxes_2,"off")
            switch n
                case '0'
                    filnam='PZT'
                case '1'
                    filnam='PZTU'
                otherwise
                    filnam='PZT'
            end
            [C0id,Rid,Lid,Cid]=identification_signal(filnam);
            Frequency=linspace(app.minFreq,app.maxFreq,1000000);
            [Freq_id,Fdat]=draw_id_signal(Rid,Lid,Cid,C0id,Frequency);
            [Freq_giv,Fdat_giv]=draw_id_signal(app.R,app.L,app.C,app.C0,Frequency);

            plot(app.UIAxes,Frequency,real(Fdat))
            hold(app.UIAxes,"on")
            axis(app.UIAxes, 'tight');
            plot(app.UIAxes,Freq_giv,real(Fdat_giv))
            legend(app.UIAxes,'original','given')
            hold(app.UIAxes,"off")

            plot(app.UIAxes_2,Frequency, -imag(Fdat))
            hold(app.UIAxes_2,"on")
            axis(app.UIAxes_2, 'tight');
            plot(app.UIAxes_2,Freq_giv, -imag(Fdat_giv))
            hold(app.UIAxes_2,"off")

            plot(app.UIAxes2,real(Fdat),-imag(Fdat))
            hold(app.UIAxes2,"on")
            axis(app.UIAxes2, 'tight');
            plot(app.UIAxes2,real(Fdat_giv),-imag(Fdat_giv))
            hold(app.UIAxes2,"off")
        end

        % Value changed function: ROhmEditField
        function ROhmEditFieldValueChanged(app, event)
            app.R = app.ROhmEditField.Value;
            
        end

        % Value changed function: CnFEditField
        function CnFEditFieldValueChanged(app, event)
            app.C = (app.CnFEditField.Value)*1e-9; 
            
        end

        % Value changed function: LmHEditField
        function LmHEditFieldValueChanged(app, event)
            app.L = (app.LmHEditField.Value)*1e-3;
            
        end

        % Value changed function: C0nFEditField
        function C0nFEditFieldValueChanged(app, event)
            app.C0 = (app.C0nFEditField.Value)*1e-9;

        end

        % Value changed function: MaterialSwitch
        function MaterialSwitchValueChanged(app, event)
            app.Material = app.MaterialSwitch.Value;
            
        end

        % Value changed function: ZakresminfkHzSlider
        function ZakresminfkHzSliderValueChanged(app, event)
            app.minFreq = (app.ZakresminfkHzSlider.Value)*1e3;
            
        end

        % Value changed function: ZakresmaxfkHzSlider
        function ZakresmaxfkHzSliderValueChanged(app, event)
            app.maxFreq = (app.ZakresmaxfkHzSlider.Value)*1e3;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wykres Bodego')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Wykres Bodego')
            xlabel(app.UIAxes_2, 'Frequency')
            ylabel(app.UIAxes_2, 'Phase')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Wykres Admitancji')
            xlabel(app.UIAxes2, 'Re [Rezystacja]')
            ylabel(app.UIAxes2, 'Im [Reaktanca]')
            zlabel(app.UIAxes2, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ROhmEditFieldLabel = uilabel(app.UIFigure);
            app.ROhmEditFieldLabel.Text = 'R [Ohm]';
            app.ROhmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ROhmEditField.ValueChangedFcn = createCallbackFcn(app, @ROhmEditFieldValueChanged, true);
            app.ROhmEditField.Value = 144.2;
            app.CFLabel = uilabel(app.UIFigure);
            app.CFLabel.Text = 'C [nF]';
            app.CnFEditField = uieditfield(app.UIFigure, 'numeric');
            app.CnFEditField.ValueChangedFcn = createCallbackFcn(app, @CnFEditFieldValueChanged, true);
            app.CnFEditField.Value = 0.0437;
            app.LmHEditFieldLabel = uilabel(app.UIFigure);
            app.LmHEditFieldLabel.Text = 'L [mH]';
            app.LmHEditField = uieditfield(app.UIFigure, 'numeric');
            app.LmHEditField.ValueChangedFcn = createCallbackFcn(app, @LmHEditFieldValueChanged, true);
            app.LmHEditField.Value = 218.7;
            app.MaterialSwitchLabel = uilabel(app.UIFigure);
            app.MaterialSwitchLabel.Text = 'Material';
            app.MaterialSwitch = uiswitch(app.UIFigure, 'slider');
            app.MaterialSwitch.Items = {'PZT', 'PZTU'};
            app.MaterialSwitch.ItemsData = [0 1];
            app.MaterialSwitch.ValueChangedFcn = createCallbackFcn(app, @MaterialSwitchValueChanged, true);
            app.MaterialSwitch.Value = 0;
            app.C0nFEditFieldLabel = uilabel(app.UIFigure);
            app.C0nFEditFieldLabel.Text = 'C0 [nF]';
            app.C0nFEditField = uieditfield(app.UIFigure, 'numeric');
            app.C0nFEditField.ValueChangedFcn = createCallbackFcn(app, @C0nFEditFieldValueChanged, true);
            app.C0nFEditField.Value = 3.79;
            app.ZakresminfkHzSliderLabel = uilabel(app.UIFigure);
            app.ZakresminfkHzSliderLabel.Text = 'Zakres min f [kHz]';
            app.ZakresminfkHzSlider = uislider(app.UIFigure);
            app.ZakresminfkHzSlider.Limits = [45 60];
            app.ZakresminfkHzSlider.ValueChangedFcn = createCallbackFcn(app, @ZakresminfkHzSliderValueChanged, true);
            app.ZakresminfkHzSlider.Value = 45;
            app.ZakresmaxfkHzSliderLabel = uilabel(app.UIFigure);
            app.ZakresmaxfkHzSliderLabel.Text = 'Zakres max f [kHz]';
            app.ZakresmaxfkHzSlider = uislider(app.UIFigure);
            app.ZakresmaxfkHzSlider.Limits = [45 60];
            app.ZakresmaxfkHzSlider.ValueChangedFcn = createCallbackFcn(app, @ZakresmaxfkHzSliderValueChanged, true);
            app.ZakresmaxfkHzSlider.Value = 60;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_RLC2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end